classdef app1_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        VariableDropDown         matlab.ui.control.DropDown
        VariableDropDownLabel    matlab.ui.control.Label
        LoadInputDataButton      matlab.ui.control.Button
        IITGUWAHATILabel         matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        CenterPanel              matlab.ui.container.Panel
        SWSTextArea              matlab.ui.control.TextArea
        DenoisingButton          matlab.ui.control.Button
        DispersionImagingButton  matlab.ui.control.Button
        SWSmapButton             matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        MeanScoreTextArea        matlab.ui.control.TextArea
        ResultTextArea           matlab.ui.control.TextArea
        MeanScoreButton          matlab.ui.control.Button
        GroupVelocityButton      matlab.ui.control.Button
        BioMedicalUltrasoundImagingGroupLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        LoadedData % Structure to hold loaded .mat data
        SelectedFile % Name of the selected .mat file
        SelectedPath % Path of the selected .mat file
    end
    
    methods (Access = private)
        
        function results = func4(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadInputDataButton
        function LoadInputDataButtonPushed(app, event)
             % Open file selection dialog
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if isequal(file, 0)
                return; % User canceled the file selection
            end
            
            % Store the file path and name for saving later
            app.SelectedFile = file;
            app.SelectedPath = path;
            
            % Load the selected .mat file
            fullPath = fullfile(path, file);
            app.LoadedData = load(fullPath);
            
            % Get field names (variable names) from the loaded data
            variableNames = fieldnames(app.LoadedData);
            
            % Populate the dropdown with the variable names
            app.VariableDropDown.Items = variableNames;
        
        end

        % Callback function
        function DenoisingButtonValueChanged(app, event)

        end

        % Button pushed function: DenoisingButton
        function DenoisingButtonPushed(app, event)
            % Get the selected variable from the dropdown
            selectedVariable = app.VariableDropDown.Value;
            
            if isempty(selectedVariable)
                uialert(app.UIFigure, 'Please select a variable from the dropdown.', 'No Variable Selected');
                return;
            end
            
            % Get the data corresponding to the selected variable
            data = app.LoadedData.(selectedVariable);
            
            % Check if the data is numeric
            if ~isnumeric(data)
                uialert(app.UIFigure, 'Selected variable is not numeric.', 'Invalid Variable');
                return;
            end
            
            % Check if the data is a 3D array
            if ndims(data) ~= 3
                uialert(app.UIFigure, 'Selected variable is not a 3D array.', 'Invalid Variable');
                return;
            end
            
            % Perform denoising using a 3D Gaussian filter
            denoisedData = imgaussfilt3(data, 2); % Standard deviation of 2
            
            % Create a new directory named after the original file (excluding extension)
            [~, name, ~] = fileparts(app.SelectedFile); % Get the original filename without extension
            newDir = fullfile(app.SelectedPath, name);
            if ~exist(newDir, 'dir')
                mkdir(newDir);
            end
            
            % Save the denoised data to a new .mat file in the new folder
            newFileName = fullfile(newDir, [name, '.mat']);
            save(newFileName, 'denoisedData');
            
            % Notify user of successful save
            uialert(app.UIFigure, 'Denoised data has been saved successfully.', 'Success');
        
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
              
        end

        % Button pushed function: GroupVelocityButton
        function GroupVelocityButtonPushed(app, event)
            % Load the denoised data
            [~, name, ext] = fileparts(app.SelectedFile); % Get the original filename without extension
            newDir = fullfile(app.SelectedPath, name);
            newFileName = fullfile(newDir, [name, ext]);
            
            if ~isfile(newFileName)
                uialert(app.UIFigure, 'Denoised data file not found.', 'File Not Found');
                return;
            end
            
            loadedData = load(newFileName, 'denoisedData');
            
            if ~isfield(loadedData, 'denoisedData')
                uialert(app.UIFigure, 'Denoised data not found in file.', 'Data Not Found');
                return;
            end
            
            denoisedData = loadedData.denoisedData;
            
            % Check if the data is numeric and a 3D array
            if ~isnumeric(denoisedData) || ndims(denoisedData) ~= 3
                uialert(app.UIFigure, 'Denoised data is not valid 3D numeric array.', 'Invalid Data');
                return;
            end
            
            % Calculate the group velocity
            groupVelocity = app.calculateGroupVelocity(denoisedData);
            
            % Display the group velocity in the text area
            app.ResultTextArea.Value = sprintf('Group Velocity:\nVx: %.2f\nVy: %.2f\nVz: %.2f\nMagnitude: %.2f', ...
                groupVelocity.Vx, groupVelocity.Vy, groupVelocity.Vz, groupVelocity.Magnitude);
        end
        
        % Function to calculate the group velocity
        function groupVelocity = calculateGroupVelocity(app, data)
            % Compute the 3D Fourier Transform of the data
            dataFFT = fftn(data);

            % Calculate the phase from the Fourier transformed data
            phase = angle(dataFFT);

            % Unwrap the phase along all three dimensions
            unwrappedPhaseX = unwrap(phase, [], 1);
            unwrappedPhaseY = unwrap(phase, [], 2);
            unwrappedPhaseZ = unwrap(phase, [], 3);

            % Assume dx, dy, dz are the spatial spacings in each dimension (example values)
            dx = 1; % Replace with actual spatial spacing
            dy = 1; % Replace with actual spatial spacing
            dz = 1; % Replace with actual spatial spacing

            % Compute the gradient of the unwrapped phase
            [gradPhaseX, ~, ~] = gradient(unwrappedPhaseX, dx);
            [~, gradPhaseY, ~] = gradient(unwrappedPhaseY, dy);
            [~, ~, gradPhaseZ] = gradient(unwrappedPhaseZ, dz);

            % Combine the group velocity components into a single structure
            groupVelocity.Vx = mean(gradPhaseX(:));
            groupVelocity.Vy = mean(gradPhaseY(:));
            groupVelocity.Vz = mean(gradPhaseZ(:));
            groupVelocity.Magnitude = sqrt(groupVelocity.Vx^2 + groupVelocity.Vy^2 + groupVelocity.Vz^2);

            
        end

        % Button pushed function: SWSmapButton
        function SWSmapButtonPushed(app, event)
           
            % Load the denoised data
            [~, name, ext] = fileparts(app.SelectedFile); % Get the original filename without extension
            newDir = fullfile(app.SelectedPath, name);
            newFileName = fullfile(newDir, [name, ext]);
            
            if ~isfile(newFileName)
                uialert(app.UIFigure, 'Denoised data file not found.', 'File Not Found');
                return;
            end
            
            loadedData = load(newFileName, 'denoisedData');
            
            if ~isfield(loadedData, 'denoisedData')
                uialert(app.UIFigure, 'Denoised data not found in file.', 'Data Not Found');
                return;
            end
            
            denoisedData = loadedData.denoisedData;
            
            % Check if the data is numeric and a 3D array
            if ~isnumeric(denoisedData) || ndims(denoisedData) ~= 3
                uialert(app.UIFigure, 'Denoised data is not valid 3D numeric array.', 'Invalid Data');
                return;
            end
            
            % Calculate the SWS map
            swsMap =app.calculateSWS(denoisedData);
            
            % Display the SWS map value in the SWS text area
            app.SWSTextArea.Value = sprintf('SWS Map calculated successfully.\nMean SWS: %.2f', mean(swsMap(:)));

        end

             % Function to calculate the SWS map
        function swsMap =calculateSWS(app, data)
            dataFFT = fftn(data);
            phase = angle(dataFFT);
            unwrappedPhaseX = unwrap(phase, [], 1);
            unwrappedPhaseY = unwrap(phase, [], 2);
            unwrappedPhaseZ = unwrap(phase, [], 3);
            dx = 1; % Replace with actual spatial spacing
            dy = 1; % Replace with actual spatial spacing
            dz = 1; % Replace with actual spatial spacing
            [gradPhaseX, ~, ~] = gradient(unwrappedPhaseX, dx);
            [~, gradPhaseY, ~] = gradient(unwrappedPhaseY, dy);
            [~, ~, gradPhaseZ] = gradient(unwrappedPhaseZ, dz);
            kx = gradPhaseX / (2 * pi * dx);
            ky = gradPhaseY / (2 * pi * dy);
            kz = gradPhaseZ / (2 * pi * dz);
            frequency = 1; % Replace with actual frequency
            swsMap = frequency ./ sqrt(kx.^2 + ky.^2 + kz.^2);
            
        
        end

        % Value changing function: SWSTextArea
        function SWSTextAreaValueChanging(app, event)
           
        end

        % Button pushed function: DispersionImagingButton
        function DispersionImagingButtonPushed(app, event)
             [~, name, ext] = fileparts(app.SelectedFile);
            newDir = fullfile(app.SelectedPath, name);
            newFileName = fullfile(newDir, [name, ext]);
            if ~isfile(newFileName)
                uialert(app.UIFigure, 'Denoised data file not found.', 'File Not Found');
                return;
            end
            loadedData = load(newFileName, 'denoisedData');
            if ~isfield(loadedData, 'denoisedData')
                uialert(app.UIFigure, 'Denoised data not found in file.', 'Data Not Found');
                return;
            end
            denoisedData = loadedData.denoisedData;
            if ~isnumeric(denoisedData) || ndims(denoisedData) ~= 3
                uialert(app.UIFigure, 'Denoised data is not valid 3D numeric array.', 'Invalid Data');
                return;
            end
            app.calculateDispersion(denoisedData);
        end
        
            % Function to calculate the linear dispersion model
        
       function calculateDispersion(app, data)
            [nx, ny, nz] = size(data);
            dx = 1; % Replace with actual spatial spacing
            dy = 1; % Replace with actual spatial spacing
            dz = 1; % Replace with actual spatial spacing
            frequencies = linspace(0, 1, nx); % Replace with actual frequency range
            velocities = zeros(1, nx);
            for i = 1:nx
                slice = squeeze(data(i, :, :));
                [gradPhaseY, gradPhaseZ] = gradient(angle(fftn(slice)), dy, dz);
                k = sqrt(gradPhaseY.^2 + gradPhaseZ.^2) / (2 * pi);
                velocities(i) = mean(k(:)) / frequencies(i);
            end
            figure;
            plot(frequencies, velocities);
            xlabel('Frequency');
            ylabel('Velocity');
            title('Dispersion Image');
        
        end

        % Callback function: MeanScoreButton, MeanScoreTextArea
        function MeanScoreButtonPushed(app, event)
                [~, name, ext] = fileparts(app.SelectedFile);
            newDir = fullfile(app.SelectedPath, name);
            newFileName = fullfile(newDir, [name, ext]);
            if ~isfile(newFileName)
                uialert(app.UIFigure, 'Denoised data file not found.', 'File Not Found');
                return;
            end
            loadedData = load(newFileName, 'denoisedData');
            if ~isfield(loadedData, 'denoisedData')
                uialert(app.UIFigure, 'Denoised data not found in file.', 'Data Not Found');
                return;
            end
            denoisedData = loadedData.denoisedData;
            if ~isnumeric(denoisedData) || ndims(denoisedData) ~= 3
                uialert(app.UIFigure, 'Denoised data is not valid 3D numeric array.', 'Invalid Data');
                return;
            end
            meanValue = mean(denoisedData(:)); % Calculate mean value
            app.MeanScoreTextArea.Value = sprintf('Mean Score: %.2f', meanValue);
        
        end

        % Value changing function: MeanScoreTextArea
        function MeanScoreTextAreaValueChanging(app, event)

             app.MeanScoreTextArea.Value = sprintf('Mean Score: %.2f', meanValue);
        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {560, 560, 560};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {560, 560};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {330, '1x', 319};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download.png');
            app.IITGUWAHATILabel = uilabel(app.LeftPanel);
            app.IITGUWAHATILabel.Interpreter = 'tex';
            app.IITGUWAHATILabel.Text = 'IIT GUWAHATI';
            app.LoadInputDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadInputDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInputDataButtonPushed, true);
            app.LoadInputDataButton.Text = 'Load Input Data';
            app.VariableDropDownLabel = uilabel(app.LeftPanel);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.LeftPanel);
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Processing';
            app.SWSmapButton = uibutton(app.CenterPanel, 'push');
            app.SWSmapButton.ButtonPushedFcn = createCallbackFcn(app, @SWSmapButtonPushed, true);
            app.SWSmapButton.Text = 'SWS map';
            app.DispersionImagingButton = uibutton(app.CenterPanel, 'push');
            app.DispersionImagingButton.ButtonPushedFcn = createCallbackFcn(app, @DispersionImagingButtonPushed, true);
            app.DispersionImagingButton.Text = 'Dispersion Imaging';
            app.DenoisingButton = uibutton(app.CenterPanel, 'push');
            app.DenoisingButton.ButtonPushedFcn = createCallbackFcn(app, @DenoisingButtonPushed, true);
            app.DenoisingButton.Text = 'Denoising';
            app.SWSTextArea = uitextarea(app.CenterPanel);
            app.SWSTextArea.ValueChangingFcn = createCallbackFcn(app, @SWSTextAreaValueChanging, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.BioMedicalUltrasoundImagingGroupLabel = uilabel(app.RightPanel);
            app.BioMedicalUltrasoundImagingGroupLabel.Interpreter = 'latex';
            app.BioMedicalUltrasoundImagingGroupLabel.Text = {'Bio Medical Ultrasound'; 'Imaging Group'};
            app.GroupVelocityButton = uibutton(app.RightPanel, 'push');
            app.GroupVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @GroupVelocityButtonPushed, true);
            app.GroupVelocityButton.Text = 'Group Velocity';
            app.MeanScoreButton = uibutton(app.RightPanel, 'push');
            app.MeanScoreButton.ButtonPushedFcn = createCallbackFcn(app, @MeanScoreButtonPushed, true);
            app.MeanScoreButton.Text = 'Mean Score';
            app.ResultTextArea = uitextarea(app.RightPanel);
            app.MeanScoreTextArea = uitextarea(app.RightPanel);
            app.MeanScoreTextArea.ValueChangedFcn = createCallbackFcn(app, @MeanScoreButtonPushed, true);
            app.MeanScoreTextArea.ValueChangingFcn = createCallbackFcn(app, @MeanScoreTextAreaValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end